classdef TrackCovid_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrackCovid                 matlab.ui.Figure
        Pulse                      matlab.ui.control.UIAxes
        HealthStats                matlab.ui.control.Table
        SPO2                       matlab.ui.control.UIAxes
        SelectdateDatePickerLabel  matlab.ui.control.Label
        SelectdateDatePicker       matlab.ui.control.DatePicker
        Breath                     matlab.ui.control.UIAxes
        Lamp                       matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        Lamp_3                     matlab.ui.control.Lamp
        KeeptrackofcourseofdiseaseLabel  matlab.ui.control.Label
        MetricslookgoodLampLabel   matlab.ui.control.Label
        MetricslookgoodLamp        matlab.ui.control.Lamp
        PayattentionLampLabel      matlab.ui.control.Label
        PayattentionLamp           matlab.ui.control.Lamp
        GetincontactwithyourphysicianLampLabel  matlab.ui.control.Label
        GetincontactwithyourphysicianLamp  matlab.ui.control.Lamp
        TRACKLabel                 matlab.ui.control.Label
        COVIDLabel                 matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = plot(app)     
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: HealthStats, HealthStats, HealthStats, 
        % SelectdateDatePicker
        function SelectdateDatePickerValueChanged(app, event)
            t = load('data.mat');
            time=0;pr=0;spo2=0;rr=0;
            
            for i=1:53
                recordings(i) = t.data(i).ref.params;
                time = cat(1,time,recordings(i).spo2.t);
                spo2 = cat(1,spo2,recordings(i).spo2.v);
                pr = cat(1,pr,recordings(i).pr.v);
                rr = cat(1,rr,recordings(i).rr.v);
            end
            
            t1 = datetime(2020,2,26,8,0,0);
            t2 = datetime(2020,4,18,8,0,0);
            t = (t1:hours(1):t2).';
            t1_x = datetime(0,0,0,'Format','HH:mm:ss');
            t2_x = datetime(22,0,0,'Format','HH:mm:ss');
            t_x=(t1_x:hours(1):t2_x).';
            t_x=table(t_x(1:24));
            health_data  = [time, spo2, pr, rr];
            health_data = fillmissing(health_data, 'previous');
            oL = length(health_data);
            health_data = interp1(1:oL, health_data, linspace(1,oL,1249));
            health_data(:,1) = [];
            
            health_data = table(t,health_data(:,1),health_data(:,2), health_data(:,3),'VariableNames',{'Date','SPO2','Pulse rate','Respiratory rate'});
            value = app.SelectdateDatePicker.Value;
            daily_data = health_data(isbetween(health_data.Date,value + '00:00:00',value + '23:30:00'),:);
            spo2_d = round(table2array(daily_data(:,2)));
            pulse_d = round(table2array(daily_data(:,3)));
            breath_d = round(table2array(daily_data(:,4)));
            bar(app.Breath,daily_data.Date,breath_d,'b'); 
            bar(app.Pulse,daily_data.Date,pulse_d,'r');ylim(app.Pulse,[40 120])
            bar(app.SPO2,daily_data.Date,spo2_d,'g');ylim(app.SPO2,[85 100])
            app.HealthStats.Data = [t_x,table(spo2_d),table(pulse_d),table(breath_d)];
            
            if mean(spo2_d) > 96
                app.Lamp.Color = [0.00,1.00,0.00];
            elseif mean(spo2_d) <= 96 && mean(spo2_d) >= 93 
                app.Lamp.Color = [1.00,0.80,0.00];
            else 
                app.Lamp.Color = [1.00,0.05,0.00];
            end
                
            if mean(pulse_d )< 85
               app.Lamp_2.Color = [0.00,1.00,0.00];
            elseif mean(pulse_d) <= 100 && mean(pulse_d) >= 85
               app.Lamp_2.Color = [1.00,0.80,0.00];
            else 
               app.Lamp_2.Color = [1.00,0.05,0.00];
            end
                    
            if mean(breath_d) < 18
               app.Lamp_3.Color = [0.00,1.00,0.00];
            elseif mean(breath_d) <= 22 && mean(breath_d) >= 18 
               app.Lamp_3.Color = [1.00,0.80,0.00];
            else 
               app.Lamp_3.Color = [1.00,0.05,0.00];
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrackCovid = uifigure('Visible', 'off');
            app.TrackCovid.Name = 'UI Figure';
            app.Pulse = uiaxes(app.TrackCovid);
            title(app.Pulse, 'Pulse rate')
            xlabel(app.Pulse, 'Time')
            ylabel(app.Pulse, 'Pulse in bpm')
            app.HealthStats = uitable(app.TrackCovid);
            app.HealthStats.ColumnName = {'Timestamp'; 'SpO2 in %'; 'Pulse rate in bpm'; 'Respiratory rate in brpm'};
            app.HealthStats.RowName = {};
            app.HealthStats.CellEditCallback = createCallbackFcn(app, @SelectdateDatePickerValueChanged, true);
            app.HealthStats.CellSelectionCallback = createCallbackFcn(app, @SelectdateDatePickerValueChanged, true);
            app.HealthStats.DisplayDataChangedFcn = createCallbackFcn(app, @SelectdateDatePickerValueChanged, true);
            app.SPO2 = uiaxes(app.TrackCovid);
            title(app.SPO2, 'SpO2 ')
            xlabel(app.SPO2, 'Time')
            ylabel(app.SPO2, 'SpO2 in %')
            app.SelectdateDatePickerLabel = uilabel(app.TrackCovid);
            app.SelectdateDatePickerLabel.Text = 'Select date:';
            app.SelectdateDatePicker = uidatepicker(app.TrackCovid);
            app.SelectdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @SelectdateDatePickerValueChanged, true);
            app.SelectdateDatePicker.Value = datetime([2020 4 7]);
            app.Breath = uiaxes(app.TrackCovid);
            title(app.Breath, 'Respiratory rate ')
            xlabel(app.Breath, 'Time')
            ylabel(app.Breath, 'Respiratory rate')
            app.Lamp = uilamp(app.TrackCovid);
            app.Lamp_2 = uilamp(app.TrackCovid);
            app.Lamp_3 = uilamp(app.TrackCovid);
            app.KeeptrackofcourseofdiseaseLabel = uilabel(app.TrackCovid);
            app.KeeptrackofcourseofdiseaseLabel.Text = 'Keep track of course of disease ';
            app.MetricslookgoodLampLabel = uilabel(app.TrackCovid);
            app.MetricslookgoodLampLabel.Text = 'Metrics look good';
            app.MetricslookgoodLamp = uilamp(app.TrackCovid);
            app.PayattentionLampLabel = uilabel(app.TrackCovid);
            app.PayattentionLampLabel.Text = 'Pay attention';
            app.PayattentionLamp = uilamp(app.TrackCovid);
            app.GetincontactwithyourphysicianLampLabel = uilabel(app.TrackCovid);
            app.GetincontactwithyourphysicianLampLabel.Text = 'Get in contact with your physician';
            app.GetincontactwithyourphysicianLamp = uilamp(app.TrackCovid);
            app.TRACKLabel = uilabel(app.TrackCovid);
            app.TRACKLabel.Text = 'TRACK';
            app.COVIDLabel = uilabel(app.TrackCovid);
            app.COVIDLabel.Text = 'COVID ';
            % Show the figure after all components are created
            app.TrackCovid.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrackCovid_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrackCovid)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrackCovid)
        end
    end
end